classdef diffShot_app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        LeftPanel2         matlab.ui.container.Panel
        diffShotButton     matlab.ui.control.Button
        ResetButton        matlab.ui.control.Button
        LoadimageButton    matlab.ui.control.Button
        RunDynamicsButton  matlab.ui.control.Button
        SliceSlider        matlab.ui.control.Slider
        SliceSliderLabel   matlab.ui.control.Label
        CenterPanel        matlab.ui.container.Panel
        RightPanel2        matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
        RightPanel         matlab.ui.container.Panel
        UIAxes2            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        scanParams; % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app, scanParams)
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            %value = app.SliceSlider.Value;

            app.SliceSlider.Limits = [app.scanParams.sliceSelectFirst app.scanParams.sliceSelectLast];
            %app.SliceSlider.Value = 1;
            app.SliceSlider.MajorTicks = 1:app.SliceSlider.Limits(2);
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;

            [~, minIdx] = min(abs(changingValue - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            changingValue = event.Source.MajorTicks(minIdx);

            imagesc(app.UIAxes, app.scanParams.vol(:,:,changingValue,1));
            
            defaultShowHere = app.scanParams.vol(:,:,changingValue,1);

            histogram(app.UIAxes2, nonzeros(defaultShowHere(:)));

        end

        % Button pushed function: RunDynamicsButton
        function RunDynamicsButtonPushed(app, event)
            
                for ii = 1:app.scanParams.volumeSelect
                    imagesc(app.UIAxes, app.scanParams.vol(:,:,round(app.SliceSlider.Value),ii));
                    
                    defaultShowHere = app.scanParams.vol(:,:,round(app.SliceSlider.Value),ii);

                    histogram(app.UIAxes2, nonzeros(defaultShowHere(:)));

                    %colormap(app.UIAxes, viridis)
                    tmp = app.scanParams.vol(:,:,round(app.SliceSlider.Value),ii);
                    tmpx = max(tmp(:));
                    clim(app.UIAxes,[0 tmpx])

                    title(app.UIAxes, ['Dynamic: ' num2str(ii)])
                    pause(0.05)


                    %keyboard
                end


        end

        % Callback function: LoadimageButton, UIAxes, UIAxes2
        function LoadimageButtonPushed(app, event)
            [filenames,pathname] = uigetfile({'*.img;*.nii;*.nii.gz','Nifti/Analyze files'},'Select the scans you want a report on... ', 'multiselect', 'on');
            
           	if ischar(filenames)
             	  filenames = {filenames};
           	elseif isnumeric(filenames)
             	  return;
            end
            cd(pathname);
            %filepath = [pathname filenames{1}];
            app.scanParams = setInitialParams_app(filenames);
            %app.scanParams = setInitialParams_app(filenames);
            
            volume = niftiread(app.scanParams(1).fileName);

            %SliceSliderValueChanging(app,event)
            SliceSliderValueChanged(app)
            %DynamicSliderValueChanged(app)
            thisSlice = 1;
            thisDyn = 1;

            defaultShow = volume(:,:,thisSlice,thisDyn);


            imagesc(app.UIAxes, defaultShow);


            histogram(app.UIAxes2, nonzeros(defaultShow(:)));

            
            %colormap(app.UIAxes, viridis)
            
            app.scanParams.vol = double(volume);

            %imagesc(volume(:,:,thisSlice, thisDyn));


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %keyboard
            imagesc(app.UIAxes, app.scanParams.vol(:,:,round(app.SliceSlider.Value),1));
            title(app.UIAxes, ['Dynamic: ' num2str(1)])
            getmax = app.scanParams.vol(:,:,:,1);
            mymax = max(getmax(:));
            clim(app.UIAxes, [0 mymax]);

            defaultShowHere = app.scanParams.vol(:,:,round(app.SliceSlider.Value),1);

            histogram(app.UIAxes2, nonzeros(defaultShowHere(:)));


        end

        % Button pushed function: diffShotButton
        function diffShotButtonPushed(app, event)
            %data = double(data);
            data = app.scanParams.vol;
            data = data(:,:,:,1:end-1);

            nX = size(data,1);
            nY = size(data,2);
            nS = size(data,3);
            nV = size(data,4);


            %data = data(data>0.05*max(data(:)));
            
            %threshold by 5% of the max
            myidx = data>0.05*max(data(:));
            tmp = data.*myidx;
            
            % use 3x mean rule inside head

            stack_mean = mean(tmp,4);

            meanthr = mean(stack_mean(:).*3);

            myidx2 = tmp<(meanthr);
            data = tmp.*myidx2;


            diffShot_stack = zeros(nX,nY,nS, nV-1);
            for ii = 1:nV-1

                %diffShot_stack(:,:,ii) = sum(data(:,:,:,ii)-data(:,:,:,(ii+1)) ./ stack_mean ,3);
                
                thedifference = data(:,:,:,ii) - data(:,:,:,(ii+1));

                thediffovermean = thedifference./stack_mean;
                prcsigch = thediffovermean .* 100;


                %diffShot_stack(:,:,:,ii) = thedifference;
                diffShot_stack(:,:,:,ii) = prcsigch;

                %diffShot_stack(:,:,:,ii) = data(:,:,:,ii)-data(:,:,:,(ii+1)) ./ stack_mean;

                imagesc(app.UIAxes, diffShot_stack(:,:,round(app.SliceSlider.Value),ii))
                defaultShowHere = diffShot_stack(:,:,round(app.SliceSlider.Value),ii);

                histogram(app.UIAxes2, nonzeros(defaultShowHere(:)));
                xlim(app.UIAxes2,[0 max(defaultShowHere(:))])
                    
                title(app.UIAxes2,'Histogram')
                title(app.UIAxes, ['Dynamic pair: ' num2str(ii) '-' num2str(ii+1)])

                pause(0.1)

                %colormap(app.UIAxes, viridis)
                colorbar(app.UIAxes)
                %myclim = max(max(diffShot_stack(:,:,ii)));
                %myclim = max(max(diffShot_stack(:,:,1)));

                clim(app.UIAxes,[0 meanthr])
                %exportgraphics(gca,[scanParams.mypath extractBefore(scanParams(xx).fileName,'.') 'diffShot_stack_nodiv.gif'],'Append',true)
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {240, '1x', 302};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel2 = uipanel(app.LeftPanel);
            app.SliceSliderLabel = uilabel(app.LeftPanel2);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.LeftPanel2);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.RunDynamicsButton = uibutton(app.LeftPanel2, 'push');
            app.RunDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @RunDynamicsButtonPushed, true);
            app.RunDynamicsButton.Text = 'RunDynamics';
            app.LoadimageButton = uibutton(app.LeftPanel2, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.ResetButton = uibutton(app.LeftPanel2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.diffShotButton = uibutton(app.LeftPanel2, 'push');
            app.diffShotButton.ButtonPushedFcn = createCallbackFcn(app, @diffShotButtonPushed, true);
            app.diffShotButton.Text = 'diffShot';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel2 = uipanel(app.CenterPanel);
            app.UIAxes = uiaxes(app.RightPanel2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diffShot_app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end